classdef WATERMARK_GUI7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WatermarkingUIFigure           matlab.ui.Figure
        VideoWatermarkingLabel         matlab.ui.control.Label
        AboutButton                    matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        WatermarkEmbedTab              matlab.ui.container.Tab
        SelectVideotobeWatermarkedButton  matlab.ui.control.Button
        SelectWatermarkButton          matlab.ui.control.Button
        EmbeddingStrengthLabel         matlab.ui.control.Label
        EmbeddingStrengthKnob          matlab.ui.control.Knob
        WatermarkButton                matlab.ui.control.Button
        CalculateButton                matlab.ui.control.Button
        PSNRforVideoLabel              matlab.ui.control.Label
        PSNRforVideoTextArea           matlab.ui.control.TextArea
        PreviewCheckBox                matlab.ui.control.CheckBox
        PreviewCheckBox_2              matlab.ui.control.CheckBox
        PreviewCheckBox_3              matlab.ui.control.CheckBox
        Lamp                           matlab.ui.control.Lamp
        Lamp2                          matlab.ui.control.Lamp
        Lamp3                          matlab.ui.control.Lamp
        Lamp4                          matlab.ui.control.Lamp
        HelpButton                     matlab.ui.control.Button
        dBLabel                        matlab.ui.control.Label
        GenerateKeysButton             matlab.ui.control.Button
        Lamp4_2                        matlab.ui.control.Lamp
        AttacksontheEmbeddedVideoTab   matlab.ui.container.Tab
        SaltPepperButton               matlab.ui.control.Button
        GaussianButton                 matlab.ui.control.Button
        SpeckleButton                  matlab.ui.control.Button
        PoissonButton                  matlab.ui.control.Button
        AttacksfortheEmbeddedVideoLabel  matlab.ui.control.Label
        RotationButton                 matlab.ui.control.Button
        ShearingButton                 matlab.ui.control.Button
        BrightnessDownButton           matlab.ui.control.Button
        BrightnessUpButton             matlab.ui.control.Button
        HistogramEqualizationButton    matlab.ui.control.Button
        EnhanceContrastButton          matlab.ui.control.Button
        AdaptiveHistogramEqualizationButton  matlab.ui.control.Button
        Lamp5                          matlab.ui.control.Lamp
        HelpButton_2                   matlab.ui.control.Button
        WatermarkExtractTab            matlab.ui.container.Tab
        ExtractButton                  matlab.ui.control.Button
        PSNRforWatermarkTextAreaLabel  matlab.ui.control.Label
        PSNRforWatermarkTextArea       matlab.ui.control.TextArea
        CalculateButton_2              matlab.ui.control.Button
        PreviewCheckBox_4              matlab.ui.control.CheckBox
        Lamp6                          matlab.ui.control.Lamp
        HelpButton_3                   matlab.ui.control.Button
        dBLabel_2                      matlab.ui.control.Label
        SelecttheWatermarkedVideoButton  matlab.ui.control.Button
        SelecttheKeysButton            matlab.ui.control.Button
        Lamp6_2                        matlab.ui.control.Lamp
        Lamp6_3                        matlab.ui.control.Lamp
    end


    properties (Access = private)
        DialogApp;
        vidObj;
        vidObj1;
        vidObj2;
        vidObj3;
        vidObj4;
        vidObj6;
        vidObj7;
        vidFrames;
        vidFrames2;
        vidFrames3;
        vidFrames4;
        vidFrames6;
        vidFrames7;
        another;
        a;
        mov3;
        mov4;
        mov5;
        mov6;
        mov7;
        mov8;
        mov9;
        psn;
        nFrames;
        nFrames1;
        nFrames2;
        h1;
        h2;
        h3;
        h4;
        another1;
        Yf_out;
        Yf_outx;
        Yf_out1;
        Yf_out2;
        Yf_out3;
        Yf_out4;
        PSNR;
        PSNR1;
        vidW;
        vidH;
        RGB;
        show1;
        show2;
        show3;
        show4;
        show5;
        show6;
        key;
        key1;
        key2;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a = app.EmbeddingStrengthKnob;
        end

        % Button pushed function: SelectVideotobeWatermarkedButton
        function SelectVideotobeWatermarkedButtonPushed(app, event)
            [Orig,PathName] = uigetfile({'*.mp4'},'Select Video to be Watermarked');
            
            app.vidObj = VideoReader(Orig);
            
            app.nFrames = app.vidObj.NumberOfFrames;
            app.vidH = app.vidObj.Height;
            app.vidW = app.vidObj.Width;
            app.Lamp.Color = 'yellow';           
            app.vidFrames = read(app.vidObj);
            numFraTo = get(app.vidObj, 'NumberOfFrames');
            numFra=300;
            samInter=floor((numFraTo-1)./(numFra-1)); 

            for k=1:numFra
                SubFrames1(:,:,:,k) = app.vidFrames(:,:,:,1+(k-1)*samInter);      
              
                SubFramesb(:,:,:,k) = imresize(SubFrames1(:,:,:,k),[320 640]);
                app.another1(:,:,:,k) = SubFramesb(:,:,:,k);
            end
            
            app.mov3 = SubFramesb;
            v = VideoWriter('Video_to_be_Watermarked.avi');
            open(v)
            writeVideo(v,SubFramesb)
            close(v)
            app.Lamp.Color = 'green';
%             if(app.PreviewCheckBox.Value)
%                 winopen Video_to_be_Watermarked.avi;
%             end
            
            app.h1 = implay(SubFramesb,app.vidObj.FrameRate);
        end

        % Button pushed function: SelectWatermarkButton
        function SelectWatermarkButtonPushed(app, event)
            [Water,PathName] = uigetfile({'*.mp4'},'Select the Watermark');
            
            app.vidObj1 = VideoReader(Water);
            
            app.nFrames1 =app.vidObj1.NumberOfFrames;
            app.Lamp2.Color = 'yellow';
            app.vidFrames = read(app.vidObj1);
            numFraTo = get(app.vidObj1, 'NumberOfFrames');
            numFra=300;
            samInter=floor((numFraTo-1)./(numFra-1)); 

            for k=1:numFra
                SubFrames(:,:,:,k) = app.vidFrames(:,:,:,1+(k-1)*samInter);
                
                SubFramesa(:,:,:,k) = imresize(SubFrames(:,:,:,k),[320 640]);
                
                app.another(:,:,:,k) = SubFramesa(:,:,:,k);
            end
            app.mov4 = SubFramesa;
            v = VideoWriter('Watermark.avi');
            open(v)
            writeVideo(v,SubFramesa)
            close(v)
            app.Lamp2.Color = 'green';
%             if(app.PreviewCheckBox_2.Value)
%                 winopen Watermark.avi;
%             end
            
            app.h2=implay(SubFramesa,app.vidObj1.FrameRate);
        end

        % Value changing function: EmbeddingStrengthKnob
        function EmbeddingStrengthKnobValueChanging(app, event)
            app.a = event.Value;
            app.Lamp3.Color = 'green';
        end

        % Button pushed function: WatermarkButton
        function WatermarkButtonPushed(app, event)
            for i = 1:300
                app.Lamp4.Color = 'yellow';
                [LL,LH,HL,HH]=dwt2(app.mov3(:,:,3,i),'haar');
            
                [LL21,LH21,HL21,HH21] = dwt2(LL,'haar');
                [LL22,LH22,HL22,HH22] = dwt2(HL,'haar');
                [LL23,LH23,HL23,HH23] = dwt2(LH,'haar');
                [LL24,LH24,HL24,HH24] = dwt2(HH,'haar');
            
                [~,~,~] = size(LL21);
            
                dct_im1 = dct2(LL21);
                dct_im2 = dct2(LL22);
                dct_im3 = dct2(LL23);
                dct_im4 = dct2(LL24);
                dct_im5 = dct2(HH21);
                dct_im6 = dct2(HH22);
                dct_im7 = dct2(HH23);
                dct_im8 = dct2(HH24);
            
                [Uy1,Sy1,Vy1] = svd(dct_im1);
                [Uy2,Sy2,Vy2] = svd(dct_im2);
                [Uy3,Sy3,Vy3] = svd(dct_im3);
                [Uy4,Sy4,Vy4] = svd(dct_im4);
                [Uy5,Sy5,Vy5] = svd(dct_im5);
                [Uy6,Sy6,Vy6] = svd(dct_im6);
                [Uy7,Sy7,Vy7] = svd(dct_im7);
                [Uy8,Sy8,Vy8] = svd(dct_im8);
                
                WM(:,:,:,i) = app.another(:,:,3,i);
                
                [~,~,LH_w,HH_w] = dwt2(WM(:,:,:,i),'haar');
            
                [LL23_w,LH23_w,HL23_w,HH23_w] = dwt2(LH_w,'haar');
                [LL24_w,LH24_w,HL24_w,HH24_w] = dwt2(HH_w,'haar');
            
                dct_im1_w = dct2(LL23_w);
                dct_im2_w = dct2(LH23_w);
                dct_im3_w = dct2(HL23_w);
                dct_im4_w = dct2(HH23_w);
                dct_im5_w = dct2(LL24_w);
                dct_im6_w = dct2(LH24_w);
                dct_im7_w = dct2(HL24_w);
                dct_im8_w = dct2(HH24_w);
            
                [~,Sy1_w,~] = svd(dct_im1_w);
                [~,Sy2_w,~] = svd(dct_im2_w);
                [~,Sy3_w,~] = svd(dct_im3_w);
                [~,Sy4_w,~] = svd(dct_im4_w);
                [~,Sy5_w,~] = svd(dct_im5_w);
                [~,Sy6_w,~] = svd(dct_im6_w);
                [~,Sy7_w,~] = svd(dct_im7_w);
                [~,Sy8_w,~] = svd(dct_im8_w);
            
                news1 = Sy1 +  app.a*Sy1_w;
                news2 = Sy2 +  app.a*Sy2_w;
                news3 = Sy3 +  app.a*Sy3_w;
                news4 = Sy4 +  app.a*Sy4_w;
                news5 = Sy5 +  app.a*Sy5_w;
                news6 = Sy6 +  app.a*Sy6_w;
                news7 = Sy7 +  app.a*Sy7_w;
                news8 = Sy8 +  app.a*Sy8_w;
            
                dLL21_r = Uy1*news1*Vy1';
                dLL22_r = Uy2*news2*Vy2';
                dLL23_r = Uy3*news3*Vy3';
                dLL24_r = Uy4*news4*Vy4';
                dHH21_r = Uy5*news5*Vy5';
                dHH22_r = Uy6*news6*Vy6';
                dHH23_r = Uy7*news7*Vy7';
                dHH24_r = Uy8*news8*Vy8';
            
                LL21_r = idct2(dLL21_r);
                LL22_r = idct2(dLL22_r);
                LL23_r = idct2(dLL23_r);
                LL24_r = idct2(dLL24_r);
                HH21_r = idct2(dHH21_r);
                HH22_r = idct2(dHH22_r);
                HH23_r = idct2(dHH23_r);
                HH24_r = idct2(dHH24_r);
            
                newLL = idwt2(LL21_r,LH21,HL21,HH21_r,'haar');
                newHL = idwt2(LL22_r,LH22,HL22,HH22_r,'haar');
                newLH = idwt2(LL23_r,LH23,HL23,HH23_r,'haar');
                newHH = idwt2(LL24_r,LH24,HL24,HH24_r,'haar');
            
                app.Yf_out(:,:,i) = idwt2(newLL,newLH,newHL,newHH,'haar');
                 
            end
                app.Yf_outx = app.Yf_out;
                app.Yf_out1 = app.Yf_out;
                app.Yf_out2 = app.Yf_out;
                app.Yf_out3 = app.Yf_out;
                app.Yf_out4 = app.Yf_out;
            for i = 1:300
              app.RGB(:,:,:,i) = uint8(cat(3,app.mov3(:,:,1,i),app.mov3(:,:,2,i),app.Yf_out(:,:,i)));
              RGBn(:,:,:,i) = uint8(cat(3,app.mov3(:,:,1,i),app.mov3(:,:,2,i),app.Yf_out(:,:,i)));
            end
            v = VideoWriter('Watermarked_Video.avi');
            open(v)
            writeVideo(v,RGBn)
            close(v)
            app.Lamp4.Color = 'green';
%             if(app.PreviewCheckBox_3.Value)
%                 winopen Watermarked_Video.avi;
%             end   
            app.h3=implay(RGBn,app.vidObj.FrameRate);
        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)

            app.Lamp6.Color = 'yellow';

            for i = 1:300 
                [fLL,fLH,fHL,fHH]=dwt2(app.mov7(:,:,3,i),'haar');
            
                [fLL21,~,~,fHH21] = dwt2(fLL,'haar');
                [fLL22,~,~,fHH22] = dwt2(fHL,'haar');
                [fLL23,~,~,fHH23] = dwt2(fLH,'haar');
                [fLL24,~,~,fHH24] = dwt2(fHH,'haar');
            
                dct_im1 = dct2(fLL21);
                dct_im2 = dct2(fLL22);
                dct_im3 = dct2(fLL23);
                dct_im4 = dct2(fLL24);
                dct_im5 = dct2(fHH21);
                dct_im6 = dct2(fHH22);
                dct_im7 = dct2(fHH23);
                dct_im8 = dct2(fHH24);
            
                [Uy1,Sy1,Vy1] = svd(dct_im1);
                [Uy2,Sy2,Vy2] = svd(dct_im2);
                [Uy3,Sy3,Vy3] = svd(dct_im3);
                [Uy4,Sy4,Vy4] = svd(dct_im4);
                [Uy5,Sy5,Vy5] = svd(dct_im5);
                [Uy6,Sy6,Vy6] = svd(dct_im6);
                [Uy7,Sy7,Vy7] = svd(dct_im7);
                [Uy8,Sy8,Vy8] = svd(dct_im8);
                          
                [wLL,wLH,wHL,wHH]=dwt2(app.mov6(:,:,3,i),'haar');
            
                [wLL21,~,~,wHH21] = dwt2(wLL,'haar');
                [wLL22,~,~,wHH22] = dwt2(wHL,'haar');
                [wLL23,~,~,wHH23] = dwt2(wLH,'haar');
                [wLL24,~,~,wHH24] = dwt2(wHH,'haar');
            
                edct_im1 = dct2(wLL21);
                edct_im2 = dct2(wLL22);
                edct_im3 = dct2(wLL23);
                edct_im4 = dct2(wLL24);
                edct_im5 = dct2(wHH21);
                edct_im6 = dct2(wHH22);
                edct_im7 = dct2(wHH23);
                edct_im8 = dct2(wHH24);
            
                [~,eSy1,~] = svd(edct_im1);
                [~,eSy2,~] = svd(edct_im2);
                [~,eSy3,~] = svd(edct_im3);
                [~,eSy4,~] = svd(edct_im4);
                [~,eSy5,~] = svd(edct_im5);
                [~,eSy6,~] = svd(edct_im6);
                [~,eSy7,~] = svd(edct_im7);
                [~,eSy8,~] = svd(edct_im8);
            
                news1 = (eSy1 - Sy1)/app.a;
                news2 = (eSy2 - Sy2)/app.a;
                news3 = (eSy3 - Sy3)/app.a;
                news4 = (eSy4 - Sy4)/app.a;
                news5 = (eSy5 - Sy5)/app.a;
                news6 = (eSy6 - Sy6)/app.a;
                news7 = (eSy7 - Sy7)/app.a;
                news8 = (eSy8 - Sy8)/app.a;
                
                [LL_w,HL_w,LH_w,HH_w] = dwt2(app.mov9(:,:,3,i),'haar');
               
                [LL23_w,LH23_w,HL23_w,HH23_w] = dwt2(LH_w,'haar');
                [LL24_w,LH24_w,HL24_w,HH24_w] = dwt2(HH_w,'haar');
            
                dct_im1_w = dct2(LL23_w);
                dct_im2_w = dct2(LH23_w);
                dct_im3_w = dct2(HL23_w);
                dct_im4_w = dct2(HH23_w);
                dct_im5_w = dct2(LL24_w);
                dct_im6_w = dct2(LH24_w);
                dct_im7_w = dct2(HL24_w);
                dct_im8_w = dct2(HH24_w);
            
                [Uy1_w,~,Vy1_w] = svd(dct_im1_w);
                [Uy2_w,~,Vy2_w] = svd(dct_im2_w);
                [Uy3_w,~,Vy3_w] = svd(dct_im3_w);
                [Uy4_w,~,Vy4_w] = svd(dct_im4_w);
                [Uy5_w,~,Vy5_w] = svd(dct_im5_w);
                [Uy6_w,~,Vy6_w] = svd(dct_im6_w);
                [Uy7_w,~,Vy7_w] = svd(dct_im7_w);
                [Uy8_w,~,Vy8_w] = svd(dct_im8_w);
                
                dLL21_r = Uy1_w*news1*Vy1_w';
                dLH21_r = Uy2_w*news2*Vy2_w';
                dHL21_r = Uy3_w*news3*Vy3_w';
                dHH21_r = Uy4_w*news4*Vy4_w';
                dLL22_r = Uy5_w*news5*Vy5_w';
                dLH22_r = Uy6_w*news6*Vy6_w';
                dHL23_r = Uy7_w*news7*Vy7_w';
                dHH24_r = Uy8_w*news8*Vy8_w';
            
                LL23_r = idct2(dLL21_r);
                LH23_r = idct2(dLH21_r);
                HL23_r = idct2(dHL21_r);
                HH23_r = idct2(dHH21_r);
                LL24_r = idct2(dLL22_r);
                LH24_r = idct2(dLH22_r);
                HL24_r = idct2(dHL23_r);
                HH24_r = idct2(dHH24_r);
            
                nLH_w = idwt2(LL23_r,LH23_r,HL23_r,HH23_r,'haar');
                nHH_w = idwt2(LL24_r,LH24_r,HL24_r,HH24_r,'haar');
            
                Mark(:,:,i) = idwt2(LL_w,nLH_w,HL_w,nHH_w,'haar');
            end
            
            for i = 1:300
                app.mov8(:,:,3,i) = Mark(:,:,i);
                RGB1(:,:,:,i) = uint8(app.mov8(:,:,:,i));
                app.psn(:,:,:,i) = RGB1(:,:,:,i);
            end
            v = VideoWriter('Extracted_Watermark.avi');
            open(v)
            writeVideo(v,RGB1)
            close(v)
            app.Lamp6.Color = 'green';
%             if(app.PreviewCheckBox_4.Value)
%                 winopen Extracted_Watermark.avi;
%             end
            
            app.h4=implay(RGB1,app.vidObj1.FrameRate);
%             set(app.h1.Parent, 'position', [25 400 640+5 320+5],'Name','Original Video');
%             set(app.h2.Parent, 'position', [25+640+30 400 640+5 320+5],'Name','Watermark');
%             set(app.h3.Parent, 'position', [25 10 640+5 320+5],'Name','Embedded Video');
%             set(app.h4.Parent, 'position', [25+640+30 10 640+5 320+5],'Name','Recovered Watermark');
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            vidH1 = app.vidObj.Height;
            vidW1 = app.vidObj.Width;
            MSET = 0;
            for i = 1:300
                In(:,:,i) = app.mov3(:,:,3,i);
            end
            for i = 1:300
                 MSE(i) = abs((1/(vidH1*vidW1))*(sum(sum(double(In(:,:,i)) - app.Yf_outx(:,:,i)))));
                 MSET = MSET + MSE(i);
            end
            MSEA = MSET/300;
            app.PSNR = 10*log((255*255)/MSEA);
            SNR = app.PSNR;
            app.PSNRforVideoTextArea.Value = num2str(SNR);
        end

        % Button pushed function: SaltPepperButton
        function SaltPepperButtonPushed(app, event)
              app.Lamp5.Color = 'yellow';  
            for i = 1:300
                    xy(:,:,:,i) = uint8(cat(3,app.mov3(:,:,1,i),app.mov3(:,:,2,i),app.Yf_out(:,:,i)));
                    salt(:,:,:,i) = imnoise(xy(:,:,:,i),'salt & pepper', 0.9);
                    app.Yf_outx(:,:,i) = salt(:,:,3,i);
            end
            v = VideoWriter('Salt_and_Pepper.avi');
            open(v)
            writeVideo(v,salt)
            close(v)
            app.Lamp5.Color = 'green';
%             winopen Salt_and_Pepper.avi;
            
            implay(salt,app.vidObj.FrameRate);
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)
              app.Lamp5.Color = 'yellow';
            for i = 1:300
                   
                xy(:,:,:,i) = uint8(cat(3,app.mov3(:,:,1,i),app.mov3(:,:,2,i),app.Yf_out(:,:,i)));
                gaus(:,:,:,i) = imnoise(xy(:,:,:,i),'gaussian', 0.1,1);
                app.Yf_outx(:,:,i) = gaus(:,:,3,i);
            end
            v = VideoWriter('Gaussian.avi');
            open(v)
            writeVideo(v,gaus)
            close(v)
            app.Lamp5.Color = 'green';
%             winopen Gaussian.avi;
            
            implay(gaus,app.vidObj.FrameRate);
        end

        % Button pushed function: SpeckleButton
        function SpeckleButtonPushed(app, event)
               app.Lamp5.Color = 'yellow';
            for i = 1:300
                   
                xy(:,:,:,i) = uint8(cat(3,app.mov3(:,:,1,i),app.mov3(:,:,2,i),app.Yf_out(:,:,i)));
                speck(:,:,:,i) = imnoise(xy(:,:,:,i),'speckle', 0.2);
                app.Yf_outx(:,:,i) = speck(:,:,3,i);
            end
            v = VideoWriter('Speckle.avi');
            open(v)
            writeVideo(v,speck)
            close(v)
            app.Lamp5.Color = 'green';
%             winopen Speckle.avi;
            
            implay(speck,app.vidObj.FrameRate);
        end

        % Button pushed function: PoissonButton
        function PoissonButtonPushed(app, event)
               app.Lamp5.Color = 'yellow';
            for i = 1:300
                   
                xy(:,:,:,i) = uint8(cat(3,app.mov3(:,:,1,i),app.mov3(:,:,2,i),app.Yf_out(:,:,i)));
                pois(:,:,:,i) = imnoise(xy(:,:,:,i),'poisson');
                app.Yf_outx(:,:,i) = pois(:,:,3,i);
            end
            v = VideoWriter('Poisson.avi');
            open(v)
            writeVideo(v,pois)
            close(v)
            app.Lamp5.Color = 'green';
%             winopen Poisson.avi;
            
            implay(pois,app.vidObj.FrameRate);
        end

        % Button pushed function: RotationButton
        function RotationButtonPushed(app, event)
               app.Lamp5.Color = 'yellow';
            for i = 1:300
                xy(:,:,:,i) = uint8(cat(3,app.mov3(:,:,1,i),app.mov3(:,:,2,i),app.Yf_out(:,:,i)));
                rota(:,:,:,i) = imrotate(xy(:,:,:,i),180);
                app.Yf_outx(:,:,i) = rota(:,:,3,i);
            end
            v = VideoWriter('Rotation.avi');
            open(v)
            writeVideo(v,rota)
            close(v)
            app.Lamp5.Color = 'green';
%             winopen Rotation.avi;
            
            implay(rota,app.vidObj.FrameRate);
        end

        % Button pushed function: ShearingButton
        function ShearingButtonPushed(app, event)
            z = 0.45;
            T = maketform('affine', [1 0 0; z 1 0; 0 0 1] );
            R = makeresampler({'cubic','nearest'},'fill');
            app.Lamp5.Color = 'yellow';
            for i = 1:300    
                xy(:,:,:,i) = uint8(cat(3,app.mov3(:,:,1,i),app.mov3(:,:,2,i),app.Yf_out(:,:,i)));
                pois(:,:,:,i) = imtransform(xy(:,:,:,i),T,R);
                sher(:,:,:,i) = imresize(pois(:,:,:,i),[320 640]);
                app.Yf_outx(:,:,i) = sher(:,:,3,i);
            end
            v = VideoWriter('Sheared.avi');
            open(v)
            writeVideo(v,sher)
            close(v)
            app.Lamp5.Color = 'green';
%             winopen Sheared.avi;
            
            implay(sher,app.vidObj.FrameRate);
        end

        % Button pushed function: BrightnessDownButton
        function BrightnessDownButtonPushed(app, event)
            app.Lamp5.Color = 'yellow';
            for i = 1:300
                xy(:,:,:,i) = uint8(cat(3,app.mov3(:,:,1,i),app.mov3(:,:,2,i),app.Yf_out(:,:,i)));
                bdown(:,:,:,i) = xy(:,:,:,i) - 100;
                app.Yf_outx(:,:,i) = bdown(:,:,3,i);
            end
            v = VideoWriter('Bdown.avi');
            open(v)
            writeVideo(v,bdown)
            close(v)
            app.Lamp5.Color = 'green';
%             winopen Bdown.avi;
            
            implay(bdown,app.vidObj.FrameRate);
        end

        % Button pushed function: BrightnessUpButton
        function BrightnessUpButtonPushed(app, event)
            app.Lamp5.Color = 'yellow';
            for i = 1:300
                xy(:,:,:,i) = uint8(cat(3,app.mov3(:,:,1,i),app.mov3(:,:,2,i),app.Yf_out(:,:,i)));
                bup(:,:,:,i) = xy(:,:,:,i) + 100;
                app.Yf_outx(:,:,i) = bup(:,:,3,i);
            end
            v = VideoWriter('Bup.avi');
            open(v)
            writeVideo(v,bup)
            close(v)
            app.Lamp5.Color = 'green';
%             winopen Bup.avi;
            
            implay(bup,app.vidObj.FrameRate);
        end

        % Button pushed function: HistogramEqualizationButton
        function HistogramEqualizationButtonPushed(app, event)
            app.Lamp5.Color = 'yellow';
            for i = 1:300
                shadow_lab(:,:,:,i) = uint8(cat(3,app.mov3(:,:,1,i),app.mov3(:,:,2,i),app.Yf_out(:,:,i)));
                max_luminosity = 25;
                L(:,:,:,i) = shadow_lab(:,:,3,i)/max_luminosity;
                shadow_histeq(:,:,:,i) = shadow_lab(:,:,:,i);
                shadow_histeq(:,:,3,i) = histeq(L(:,:,:,i))*max_luminosity;
                hst(:,:,:,i) = shadow_histeq(:,:,:,i);
                app.Yf_outx(:,:,i) = hst(:,:,3,i);
            end
            v = VideoWriter('Histogrameq.avi');
            open(v)
            writeVideo(v,hst)
            close(v)
            app.Lamp5.Color = 'green';
%             winopen Histogrameq.avi;
            
            implay(hst,app.vidObj.FrameRate);
        end

        % Button pushed function: EnhanceContrastButton
        function EnhanceContrastButtonPushed(app, event)
           app.Lamp5.Color = 'yellow';
            for i = 1:300
                shadow_lab(:,:,:,i) = uint8(cat(3,app.mov3(:,:,1,i),app.mov3(:,:,2,i),app.Yf_out(:,:,i)));
                max_luminosity = 25;
                L(:,:,:,i) = shadow_lab(:,:,3,i)/max_luminosity;
                shadow_imadjust(:,:,:,i) = shadow_lab(:,:,:,i);
                shadow_imadjust(:,:,3,i) = imadjust(L(:,:,:,i))*max_luminosity;
                cont(:,:,:,i) = shadow_imadjust(:,:,:,i);
                app.Yf_outx(:,:,i) = cont(:,:,3,i);
           end
            v = VideoWriter('Enhancecont.avi');
            open(v)
            writeVideo(v,cont)
            close(v)
            app.Lamp5.Color = 'green';
%             winopen Enhancecont.avi;
            
            implay(cont,app.vidObj.FrameRate);
        end

        % Button pushed function: AdaptiveHistogramEqualizationButton
        function AdaptiveHistogramEqualizationButtonPushed(app, event)
            app.Lamp5.Color = 'yellow';
            for i = 1:300
                shadow_lab(:,:,:,i) = uint8(cat(3,app.mov3(:,:,1,i),app.mov3(:,:,2,i),app.Yf_out(:,:,i)));
                max_luminosity = 25;
                L(:,:,:,i) = shadow_lab(:,:,3,i)/max_luminosity;
                shadow_adapthisteq(:,:,:,i) = shadow_lab(:,:,:,i);
                shadow_adapthisteq(:,:,3,i) = adapthisteq(L(:,:,:,i))*max_luminosity;
                adapthist(:,:,:,i) = shadow_adapthisteq(:,:,:,i);
                app.Yf_outx(:,:,i) = adapthist(:,:,3,i);
            end
            v = VideoWriter('Adapthist.avi');
            open(v)
            writeVideo(v,adapthist)
            close(v)
            app.Lamp5.Color = 'green';
%             winopen Adapthist.avi;
            
            implay(adapthist,app.vidObj.FrameRate);
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            fig = app.WatermarkingUIFigure;
            message = sprintf('Invisible Semi-Fragile Watermarking and Steganography for Content Authentication and Data Hiding for Digital Videos \n done by \n Aditya B P, Avaneesh U G K, Akshay Murthy and Adithya K.\n Students of VVCE, ECE Department\n Under the Guidance of Mr. Sandeep R and Ms. Kavyashree B');
             uialert(fig,message,'About',...
            'Icon','info');
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            fig = app.WatermarkingUIFigure;
            message = sprintf('Step 1 : Select the Video to be Watermarked. ');
            message1= sprintf('\n Step 2 : Adjust the Embedding Strength Knob.');
            message2= sprintf('\n Step 3 : Select the Watermark to hide in the original video.');
            message3= sprintf('\n Step 4 : Press Watermark to start Watermarking Procedure.');
            message6= sprintf('\n Step 5 : Press Calculate to calculate the PSNR.');
            message7= sprintf('\n Step 6 : Generate the Key.');
            message9= [message,message1,message2,message3,message6,message7];
             uialert(fig,message9,'Help',...
            'Icon','info');
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            vidH2 = app.vidObj1.Height;
            vidW2 = app.vidObj1.Width;
            MSET1 = 0;
            for i = 1:300
                In1(:,:,i) = app.mov4(:,:,3,i);
            end
            for i = 1:300
                 MSE1(i) = abs((1/(vidH2*vidW2))*(sum(sum(double(In1(:,:,i)) - app.psn(:,:,i)))));
                 MSET1 = MSET1 + MSE1(i);
            end
            MSEA1 = MSET1/300;
            app.PSNR1 = 10*log((255*255)/MSEA1);
            SNR1 = app.PSNR1;
            app.PSNRforWatermarkTextArea.Value = num2str(SNR1);
        end

        % Value changed function: PreviewCheckBox
        function PreviewCheckBoxValueChanged(app, event)
            app.PreviewCheckBox.Value;
        end

        % Button pushed function: HelpButton_2
        function HelpButton_2Pushed(app, event)
            fig = app.WatermarkingUIFigure;
            message4= sprintf('\n Use any desired attack on the Embedded Video. ');
            uialert(fig,message4,'Help',...
            'Icon','info');
        end

        % Button pushed function: HelpButton_3
        function HelpButton_3Pushed(app, event)
            fig = app.WatermarkingUIFigure;
            message3= sprintf('\n Step 1 : Select the Watermarked Video. ');
            message4= sprintf('\n Step 2 : Select the Keys in the exact order. ');
            message5= sprintf('\n Step 3 : Press Extract Button to extract the Embedded Watermark. ');
            message6= sprintf('\n Step 4 : Press Calculate to calculate the PSNR.');
            message7= [message3,message4,message5,message6];
            uialert(fig,message7,'Help',...
            'Icon','info');
        end

        % Button pushed function: GenerateKeysButton
        function GenerateKeysButtonPushed(app, event)
            app.Lamp4_2.Color = 'yellow';
            v = VideoWriter('key1.avi');
            open(v)
            for i =1:300
                app.key(:,:,1,i) = zeros(320,640);
                app.key(:,:,2,i) = zeros(320,640);
                app.key(:,:,3,i) = app.another1(:,:,3,i);
                
                keya(:,:,:,i)=app.key(:,:,:,i);
                writeVideo(v,double(keya(:,:,:,i))/256)
            end
            close(v)
            v = VideoWriter('key2.avi');
            open(v)
            for i =1:300
%                 app.key1(:,:,:,i) = uint8(cat(2,app.another(:,:,1,i),app.another(:,:,2,i)));
                app.key1(:,:,1,i) = app.another(:,:,1,i);
                 app.key1(:,:,2,i) = app.another(:,:,2,i);
                 app.key1(:,:,3,i) = zeros(320,640);
                keyb(:,:,:,i)=app.key1(:,:,:,i);
                writeVideo(v,double(keyb(:,:,:,i))/256)
            end
            close(v)
            v = VideoWriter('key3.avi');
            open(v)
            for i =1:300
                app.key2(:,:,1,i) = zeros(320,640);
                app.key2(:,:,2,i) = zeros(320,640);
                app.key2(:,:,3,i) = app.another(:,:,3,i);
                keyc(:,:,:,i)=app.key2(:,:,:,i);
                writeVideo(v,double(keyc(:,:,:,i))/256)
            end
            close(v)
            app.Lamp4_2.Color = 'green';
        end

        % Button pushed function: SelecttheWatermarkedVideoButton
        function SelecttheWatermarkedVideoButtonPushed(app, event)
            [Orig,PathName] = uigetfile({'*.avi'},'Select Watermarked Video to be Extracted');
            app.Lamp6_2.Color = 'yellow';
            app.vidObj3 = VideoReader(Orig);      
            app.vidFrames3 = read(app.vidObj3);
            app.mov6 = app.vidFrames3;
            app.Lamp6_2.Color = 'green';
        end

        % Button pushed function: SelecttheKeysButton
        function SelecttheKeysButtonPushed(app, event)
            [Orig,PathName] = uigetfile({'*.avi'},'Select the First Key');
            [Orig2,PathName] = uigetfile({'*.avi'},'Select the Second Key');
            [Orig3,PathName] = uigetfile({'*.avi'},'Select the Third Key');
            app.Lamp6_3.Color = 'yellow';
            app.vidObj4 = VideoReader(Orig);
            app.vidFrames4 = read(app.vidObj4);
            for i = 1:300
            app.mov7(:,:,:,i) = app.vidFrames4(:,:,:,i);
            end
            app.vidObj6 = VideoReader(Orig2);
            app.vidFrames6 = read(app.vidObj6);
            for i = 1:300
            app.mov8(:,:,:,i) = app.vidFrames6(:,:,:,i);
            end
            app.vidObj7 = VideoReader(Orig3);
            app.vidFrames7 = read(app.vidObj7);
            for i = 1:300
            app.mov9(:,:,:,i) = app.vidFrames7(:,:,:,i);
            end
            app.Lamp6_3.Color = 'green';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WatermarkingUIFigure = uifigure;
            app.WatermarkingUIFigure.Name = 'Watermarking';
            app.VideoWatermarkingLabel = uilabel(app.WatermarkingUIFigure);
            app.VideoWatermarkingLabel.Text = 'Video Watermarking';
            app.AboutButton = uibutton(app.WatermarkingUIFigure, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.TabGroup = uitabgroup(app.WatermarkingUIFigure);
            app.WatermarkEmbedTab = uitab(app.TabGroup);
            app.WatermarkEmbedTab.Title = 'Watermark Embed';
            app.SelectVideotobeWatermarkedButton = uibutton(app.WatermarkEmbedTab, 'push');
            app.SelectVideotobeWatermarkedButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVideotobeWatermarkedButtonPushed, true);
            app.SelectVideotobeWatermarkedButton.Text = '1. Select Video to be Watermarked';
            app.SelectWatermarkButton = uibutton(app.WatermarkEmbedTab, 'push');
            app.SelectWatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWatermarkButtonPushed, true);
            app.SelectWatermarkButton.Text = '2. Select  Watermark';
            app.EmbeddingStrengthLabel = uilabel(app.WatermarkEmbedTab);
            app.EmbeddingStrengthLabel.Text = '3. Embedding Strength';
            app.EmbeddingStrengthKnob = uiknob(app.WatermarkEmbedTab, 'continuous');
            app.EmbeddingStrengthKnob.Limits = [0 5];
            app.EmbeddingStrengthKnob.ValueChangingFcn = createCallbackFcn(app, @EmbeddingStrengthKnobValueChanging, true);
            app.WatermarkButton = uibutton(app.WatermarkEmbedTab, 'push');
            app.WatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @WatermarkButtonPushed, true);
            app.WatermarkButton.Text = '4. Watermark';
            app.CalculateButton = uibutton(app.WatermarkEmbedTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = '6. Calculate';
            app.PSNRforVideoLabel = uilabel(app.WatermarkEmbedTab);
            app.PSNRforVideoLabel.Text = 'PSNR for Video =';
            app.PSNRforVideoTextArea = uitextarea(app.WatermarkEmbedTab);
            app.PSNRforVideoTextArea.Editable = 'off';
            app.PreviewCheckBox = uicheckbox(app.WatermarkEmbedTab);
            app.PreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreviewCheckBoxValueChanged, true);
            app.PreviewCheckBox.Text = 'Preview';
            app.PreviewCheckBox_2 = uicheckbox(app.WatermarkEmbedTab);
            app.PreviewCheckBox_2.Text = 'Preview';
            app.PreviewCheckBox_3 = uicheckbox(app.WatermarkEmbedTab);
            app.PreviewCheckBox_3.Text = 'Preview';
            app.Lamp = uilamp(app.WatermarkEmbedTab);
            app.Lamp2 = uilamp(app.WatermarkEmbedTab);
            app.Lamp3 = uilamp(app.WatermarkEmbedTab);
            app.Lamp4 = uilamp(app.WatermarkEmbedTab);
            app.HelpButton = uibutton(app.WatermarkEmbedTab, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.dBLabel = uilabel(app.WatermarkEmbedTab);
            app.dBLabel.Text = 'dB';
            app.GenerateKeysButton = uibutton(app.WatermarkEmbedTab, 'push');
            app.GenerateKeysButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateKeysButtonPushed, true);
            app.GenerateKeysButton.Text = '5. Generate Keys';
            app.Lamp4_2 = uilamp(app.WatermarkEmbedTab);
            app.AttacksontheEmbeddedVideoTab = uitab(app.TabGroup);
            app.AttacksontheEmbeddedVideoTab.Title = 'Attacks on the Embedded Video';
            app.SaltPepperButton = uibutton(app.AttacksontheEmbeddedVideoTab, 'push');
            app.SaltPepperButton.ButtonPushedFcn = createCallbackFcn(app, @SaltPepperButtonPushed, true);
            app.SaltPepperButton.Text = 'Salt & Pepper';
            app.GaussianButton = uibutton(app.AttacksontheEmbeddedVideoTab, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.SpeckleButton = uibutton(app.AttacksontheEmbeddedVideoTab, 'push');
            app.SpeckleButton.ButtonPushedFcn = createCallbackFcn(app, @SpeckleButtonPushed, true);
            app.SpeckleButton.Text = 'Speckle';
            app.PoissonButton = uibutton(app.AttacksontheEmbeddedVideoTab, 'push');
            app.PoissonButton.ButtonPushedFcn = createCallbackFcn(app, @PoissonButtonPushed, true);
            app.PoissonButton.Text = 'Poisson';
            app.AttacksfortheEmbeddedVideoLabel = uilabel(app.AttacksontheEmbeddedVideoTab);
            app.AttacksfortheEmbeddedVideoLabel.Text = ' Attacks for the Embedded Video';
            app.RotationButton = uibutton(app.AttacksontheEmbeddedVideoTab, 'push');
            app.RotationButton.ButtonPushedFcn = createCallbackFcn(app, @RotationButtonPushed, true);
            app.RotationButton.Text = 'Rotation';
            app.ShearingButton = uibutton(app.AttacksontheEmbeddedVideoTab, 'push');
            app.ShearingButton.ButtonPushedFcn = createCallbackFcn(app, @ShearingButtonPushed, true);
            app.ShearingButton.Text = 'Shearing';
            app.BrightnessDownButton = uibutton(app.AttacksontheEmbeddedVideoTab, 'push');
            app.BrightnessDownButton.ButtonPushedFcn = createCallbackFcn(app, @BrightnessDownButtonPushed, true);
            app.BrightnessDownButton.Text = 'Brightness Down';
            app.BrightnessUpButton = uibutton(app.AttacksontheEmbeddedVideoTab, 'push');
            app.BrightnessUpButton.ButtonPushedFcn = createCallbackFcn(app, @BrightnessUpButtonPushed, true);
            app.BrightnessUpButton.Text = 'Brightness Up';
            app.HistogramEqualizationButton = uibutton(app.AttacksontheEmbeddedVideoTab, 'push');
            app.HistogramEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEqualizationButtonPushed, true);
            app.HistogramEqualizationButton.Text = 'Histogram Equalization';
            app.EnhanceContrastButton = uibutton(app.AttacksontheEmbeddedVideoTab, 'push');
            app.EnhanceContrastButton.ButtonPushedFcn = createCallbackFcn(app, @EnhanceContrastButtonPushed, true);
            app.EnhanceContrastButton.Text = 'Enhance Contrast';
            app.AdaptiveHistogramEqualizationButton = uibutton(app.AttacksontheEmbeddedVideoTab, 'push');
            app.AdaptiveHistogramEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @AdaptiveHistogramEqualizationButtonPushed, true);
            app.AdaptiveHistogramEqualizationButton.Text = 'Adaptive Histogram Equalization';
            app.Lamp5 = uilamp(app.AttacksontheEmbeddedVideoTab);
            app.HelpButton_2 = uibutton(app.AttacksontheEmbeddedVideoTab, 'push');
            app.HelpButton_2.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_2Pushed, true);
            app.HelpButton_2.Text = 'Help';
            app.WatermarkExtractTab = uitab(app.TabGroup);
            app.WatermarkExtractTab.Title = 'Watermark Extract';
            app.ExtractButton = uibutton(app.WatermarkExtractTab, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Text = '3. Extract';
            app.PSNRforWatermarkTextAreaLabel = uilabel(app.WatermarkExtractTab);
            app.PSNRforWatermarkTextAreaLabel.Text = 'PSNR for Watermark =';
            app.PSNRforWatermarkTextArea = uitextarea(app.WatermarkExtractTab);
            app.PSNRforWatermarkTextArea.Editable = 'off';
            app.CalculateButton_2 = uibutton(app.WatermarkExtractTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = '4. Calculate';
            app.PreviewCheckBox_4 = uicheckbox(app.WatermarkExtractTab);
            app.PreviewCheckBox_4.Text = 'Preview';
            app.Lamp6 = uilamp(app.WatermarkExtractTab);
            app.HelpButton_3 = uibutton(app.WatermarkExtractTab, 'push');
            app.HelpButton_3.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_3Pushed, true);
            app.HelpButton_3.Text = 'Help';
            app.dBLabel_2 = uilabel(app.WatermarkExtractTab);
            app.dBLabel_2.Text = 'dB';
            app.SelecttheWatermarkedVideoButton = uibutton(app.WatermarkExtractTab, 'push');
            app.SelecttheWatermarkedVideoButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttheWatermarkedVideoButtonPushed, true);
            app.SelecttheWatermarkedVideoButton.Text = '1. Select the Watermarked Video';
            app.SelecttheKeysButton = uibutton(app.WatermarkExtractTab, 'push');
            app.SelecttheKeysButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttheKeysButtonPushed, true);
            app.SelecttheKeysButton.Text = '2. Select the Keys';
            app.Lamp6_2 = uilamp(app.WatermarkExtractTab);
            app.Lamp6_3 = uilamp(app.WatermarkExtractTab);
        end
    end

    methods (Access = public)

        % Construct app
        function app = WATERMARK_GUI7

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WatermarkingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WatermarkingUIFigure)
        end
    end
end